classdef multiThreadAudioApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        InputSelectButtonGroup       matlab.ui.container.ButtonGroup
        MicrophoneButton             matlab.ui.control.RadioButton
        AudioFileButton              matlab.ui.control.RadioButton
        TimevariablesPanel           matlab.ui.container.Panel
        GridLayout112_2              matlab.ui.container.GridLayout
        samplingfrequencyEditField   matlab.ui.control.NumericEditField
        samplingfrequencyEditFieldLabel  matlab.ui.control.Label
        missedFrameCountUIC          matlab.ui.control.Spinner
        outGainLabel_2               matlab.ui.control.Label
        samplesPerFrameUIC           matlab.ui.control.Spinner
        gainAlphaLabel_2             matlab.ui.control.Label
        frameTimeUIC                 matlab.ui.control.Spinner
        MassKgLabel_2                matlab.ui.control.Label
        ParametersPanel              matlab.ui.container.Panel
        GridLayout112                matlab.ui.container.GridLayout
        outGainUIC                   matlab.ui.control.Spinner
        outGainLabel                 matlab.ui.control.Label
        binIncreUIC                  matlab.ui.control.Spinner
        gainAlphaLabel               matlab.ui.control.Label
        gainAlphaUIC                 matlab.ui.control.Spinner
        MassKgLabel                  matlab.ui.control.Label
        InputAudioFileNameEditField  matlab.ui.control.EditField
        InputAudioFileNameEditFieldLabel  matlab.ui.control.Label
        PlayAudiowithStationaryNoiseSuppressionLabel  matlab.ui.control.Label
        PlayAudioButton              matlab.ui.control.StateButton
        UIAxes                       matlab.ui.control.UIAxes
    end


    properties (Access = public)
        num  % Numerator for filter
        den  % Denominator for filter

        FileIn % Audio file source object
        micIn  % audio Device reader object
        inputChanged  % flag indicating change of input

        EQSetting % EQ bypass state

        MyFilter % Filter Object

        cFreq     % center frequency in Hz
        fs        % sampling frequency in Hz

        %        controlChangeFlag  % flag to indicate a change in parameters
        fObj      % future object running in the background
        flagSndData     % flag set inside timer callback
        timObj          % timer object
        dataSnd         % data used to pass into audio out obj
        audioOutObj     % audio write device object
        nUnderruns      % counting underruns from audio device write
        asynBuff        % asynchronous buffer
        procDataObj     % process data object
        param           % parameters used in process data object
        count = 0
        readSize        % size of one frame of data read from file
        useTime         % for debugging, record of device hold time
        missedFrame = 0 % number of data frames repeated in playing
        timerPeriod     % period time for the timer interrupt
        hLine
    end

    properties (Access = public)
        fileName            % audio input file name
        fileNameChangedFlag % flag indicates name has changed
    end

    methods (Access = private)

        function timerCallbk(app, mTimer,~)

            % send the processed data to the device player
            % check the time that is pending for the driver to retrieve
            out = app.dataSnd * app.outGainUIC.Value;
            ticTime = tic();
            app.nUnderruns = app.nUnderruns + app.audioOutObj(out);
            measTime = toc(ticTime);
            % delaying the time such that the pending time per call is
            % reduced
            pause(measTime/2);

            app.count = app.count +1;

            if app.count < 50
                app.useTime(app.count) = measTime;
            end

            % check to see if number of frames played and processed are the
            % same
            countdif = app.count - app.procDataObj.countSN;  
            if countdif < 0
                disp('check')
            end
            if countdif ~= app.missedFrame
                app.missedFrame = countdif;
                app.missedFrameCountUIC.Value = app.missedFrame;
                disp(['number of repeated frames ' num2str(app.missedFrame) ])
            end

            % plot the played data frame
            %plot(app.UIAxes,app.dataSnd); %
            set(app.hLine,'YData', out(1:1024));
            drawnow limitrate;
            
            % read one data frame of data
            if app.AudioFileButton.Value == 1
                in = app.FileIn();
            else
                in = app.micIn();
            end
            out = single(in(:,1));

% this section uses background thread
            localObj = app.procDataObj;
            app.fObj = parfeval(backgroundPool, @localObj.doingStep, 2, out);
            afterEach(app.fObj, @app.evtCb, 0, "PassFuture", true);

%  This section do not use separate thread
%             localObj = app.procDataObj;
%             [out, localObj] = app.procDataObj.doingStep(out);
%             localObj.param.gainAlpha = app.gainAlphaUIC.Value;
%             localObj.param.binIncre = app.binIncreUIC.Value;
%             app.procDataObj = localObj;
%             app.dataSnd = single(out);

        end

        % Callback function after the background task is done 
        function evtCb(app, pp)
            [out, localObj] = fetchOutputs(pp);
            % update parameters
            localObj.param.gainAlpha = app.gainAlphaUIC.Value;
            localObj.param.binIncre = app.binIncreUIC.Value;

            app.procDataObj = localObj;
            app.dataSnd = single(out);
        end
        
        function updateFileTime(app)
            app.FileIn = dsp.AudioFileReader(app.fileName,'PlayCount',inf);
            Fs = app.FileIn.SampleRate;
            app.fs = Fs;
            app.readSize = round(app.timerPeriod*Fs);
            app.samplingfrequencyEditField.Value = Fs;
            app.FileIn.SamplesPerFrame = app.readSize; 
            app.samplesPerFrameUIC.Value = app.FileIn.SamplesPerFrame;

        end
        
        function updateMicTime(app)

            % fs, readsize ... all stays the same            
            app.micIn.SampleRate = app.fs;
            app.readSize = round(app.timerPeriod*app.fs);  % time period might be changed
            app.micIn.SamplesPerFrame = app.readSize;
            setup(app.micIn);            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          
            % Load up an audio source file
            app.fileNameChangedFlag = 0;
            app.fileName = 'speech_dft.mp3'; % this default file comes with MATLAB;
            app.FileIn = dsp.AudioFileReader(app.fileName,'PlayCount',inf);   
            app.micIn = audioDeviceReader;
            
            app.inputChanged = 0;     % set flag to no change of input
            % note that the only way to change fs is to use a different File
            if app.AudioFileButton.Value == 1
               app.fs = app.FileIn.SampleRate;
            else
               app.fs = app.micIn.SampleRate; % first time mic use default fs
            end
            % other times mic would follow fs from the audio file
            
            app.samplingfrequencyEditField.Value = app.fs;
            app.InputAudioFileNameEditField.Value = app.fileName;
            app.timerPeriod = app.frameTimeUIC.Value;
            app.readSize = round(app.timerPeriod*app.fs);
            app.samplesPerFrameUIC.Value = app.FileIn.SamplesPerFrame;

            app.FileIn.SamplesPerFrame = app.readSize;
            app.micIn.SamplesPerFrame = app.readSize;
            setup(app.micIn);

            % process data object
            app.param = struct('binIncre', 0.05, 'gainAlpha', 0.95, 'hh', ...
                hanning(8820), 'frameSize', 4410, 'fftsize',8820);

            app.procDataObj = callBkProc_msys(app.param);

            % prepare/wakeup the threads
            out = rand(4410,1);
            localObj = app.procDataObj;
            app.fObj = parfeval(backgroundPool, @localObj.doingStep, 2, out);
            fetchOutputs(app.fObj);

            % prepare plot area 
           % prepare ploting handle
           app.UIAxes.Toolbar = [];                  %  user toolbar would cause drop frames           
           disableDefaultInteractivity(app.UIAxes);  %  user options that would cause drop frames 
           app.hLine = line(app.UIAxes, ...
                'XData',[], ...
                'YData',[], ...
                'Color', 'black', ...
                'Marker','none');
            app.hLine.XData = 1:1024;
            app.hLine.YData = zeros(1,1024);
            axis(app.UIAxes, [1 1024 -1 1] );


        end

        % Value changed function: PlayAudioButton
        function PlayAudioPlayAudioButtonPushed(app, event)

            app.nUnderruns = 0;
            app.flagSndData = 0;

            value = app.PlayAudioButton.Value;

            if(value)
                % start playing

                app.PlayAudioButton.Text = 'Playing...';


                app.audioOutObj = audioDeviceWriter('BufferSize',app.readSize,'SampleRate',app.fs);

                app.asynBuff = dsp.AsyncBuffer;

                % update param and reset instance
                app.param.frameSize = app.readSize;
                app.param.fftsize = app.param.frameSize*2;
                app.param.hh = hanning(app.param.fftsize);
                app.procDataObj = callBkProc_msys(app.param);

                % read data and start timer 
                if app.AudioFileButton.Value == 1
                    in = app.FileIn();
                else
                    in = app.micIn();
                end
                    out = single(in(:,1));

                    app.dataSnd = out;
                    app.useTime = zeros(50,1);

                    app.flagSndData = 0;
                    app.timObj = timer('Period',app.timerPeriod,'TasksToExecute', 32768,'ExecutionMode','fixedRate','TimerFcn',@app.timerCallbk);

                    start(app.timObj);

                    y = app.dataSnd;
                    %plot(app.UIAxes,y); 
                    set(app.hLine,'YData', y(1:1024));
                    drawnow limitrate;

            else
                % stop playing
                if ~isempty(app.timObj)
                    stop(app.timObj);
                    delete(app.timObj);
                    app.timObj = [];
                    %release(app.micIn);
                end
                app.PlayAudioButton.Text = 'Play Audio';
                app.count = 0;
                app.procDataObj.countSN = 0;

                % check if input selection has changed
                if 1  %app.inputChanged == 1
                    
                    % check change of audio file
                    if app.AudioFileButton.Value == 1
                        
                        %if app.fileNameChangedFlag==1

                            % update time info
                            updateFileTime(app);
                            app.fileNameChangedFlag = 0;
                        %end
                    else
                        app.micIn = audioDeviceReader;
                        updateMicTime(app);
                    end
                    app.inputChanged = 0;
                else
                    % not change in input select

                    if app.fileNameChangedFlag==1
                        % update time info
                        updateFileTime(app);
                        app.fileNameChangedFlag = 0;
                    end
                    
                end
                
            end
        end

        % Value changed function: InputAudioFileNameEditField
        function InputAudioFileNameEditFieldValueChanged(app, event)
            value = app.InputAudioFileNameEditField.Value;
            app.fileName = value;

            if app.PlayAudioButton.Value == 1
                app.fileNameChangedFlag = 1;
            else
                if app.AudioFileButton.Value == 1
                    
                    % update time info
                    updateFileTime(app);
                else
                    app.fileNameChangedFlag = 1;
                end
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.fObj)
                cancel(app.fObj);
            end
            delete(app.timObj);
            delete(app);

        end

        % Value changed function: samplesPerFrameUIC
        function samplesPerFrameUICValueChanged(app, event)
            value = app.samplesPerFrameUIC.Value;
            
            app.fileNameChangedFlag = 1;
            app.timerPeriod = value/ app.fs; 
            app.frameTimeUIC.Value = app.timerPeriod;

            app.PlayAudioButton.Value = 0;
            PlayAudioPlayAudioButtonPushed(app );
        end

        % Value changed function: frameTimeUIC
        function frameTimeUICValueChanged(app, event)
            value = app.frameTimeUIC.Value;

            app.fileNameChangedFlag = 1;
            app.timerPeriod = value; 

            app.PlayAudioButton.Value = 0;
            PlayAudioPlayAudioButtonPushed(app );
            
        end

        % Selection changed function: InputSelectButtonGroup
        function InputSelectButtonGroupSelectionChanged(app, event)
            buttonGroupChanged = app.InputSelectButtonGroup.SelectedObject;

            app.inputChanged = 1;

            % force STOP play
            app.PlayAudioButton.Value = 0;
            PlayAudioPlayAudioButtonPushed(app );

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Audio')
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'Y')
            app.PlayAudioButton = uibutton(app.UIFigure, 'state');
            app.PlayAudioButton.ValueChangedFcn = createCallbackFcn(app, @PlayAudioPlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio!';
            app.PlayAudiowithStationaryNoiseSuppressionLabel = uilabel(app.UIFigure);
            app.PlayAudiowithStationaryNoiseSuppressionLabel.Text = 'Play Audio with Stationary Noise Suppression';
            app.InputAudioFileNameEditFieldLabel = uilabel(app.UIFigure);
            app.InputAudioFileNameEditFieldLabel.Text = 'Input Audio File Name';
            app.InputAudioFileNameEditField = uieditfield(app.UIFigure, 'text');
            app.InputAudioFileNameEditField.ValueChangedFcn = createCallbackFcn(app, @InputAudioFileNameEditFieldValueChanged, true);
            app.InputAudioFileNameEditField.Value = 'speech_dft.mp3';
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.GridLayout112 = uigridlayout(app.ParametersPanel);
            app.MassKgLabel = uilabel(app.GridLayout112);
            app.MassKgLabel.Text = 'alpha';
            app.gainAlphaUIC = uispinner(app.GridLayout112);
            app.gainAlphaUIC.Step = 0.05;
            app.gainAlphaUIC.Limits = [0.05 0.95];
            app.gainAlphaUIC.Value = 0.9;
            app.gainAlphaLabel = uilabel(app.GridLayout112);
            app.gainAlphaLabel.Text = 'bin Increment';
            app.binIncreUIC = uispinner(app.GridLayout112);
            app.binIncreUIC.Step = 0.002;
            app.binIncreUIC.Limits = [0.001 0.5];
            app.binIncreUIC.Value = 0.1;
            app.outGainLabel = uilabel(app.GridLayout112);
            app.outGainLabel.Text = 'out Gain';
            app.outGainUIC = uispinner(app.GridLayout112);
            app.outGainUIC.Step = 0.1;
            app.outGainUIC.Limits = [0.1 5];
            app.outGainUIC.Value = 0.8;
            app.TimevariablesPanel = uipanel(app.UIFigure);
            app.TimevariablesPanel.Title = 'Time variables';
            app.GridLayout112_2 = uigridlayout(app.TimevariablesPanel);
            app.MassKgLabel_2 = uilabel(app.GridLayout112_2);
            app.MassKgLabel_2.Text = 'frame time';
            app.frameTimeUIC = uispinner(app.GridLayout112_2);
            app.frameTimeUIC.Step = 0.01;
            app.frameTimeUIC.Limits = [0.01 0.4];
            app.frameTimeUIC.ValueChangedFcn = createCallbackFcn(app, @frameTimeUICValueChanged, true);
            app.frameTimeUIC.Value = 0.1;
            app.gainAlphaLabel_2 = uilabel(app.GridLayout112_2);
            app.gainAlphaLabel_2.Text = 'samples per frame';
            app.samplesPerFrameUIC = uispinner(app.GridLayout112_2);
            app.samplesPerFrameUIC.Step = 100;
            app.samplesPerFrameUIC.Limits = [100 48000];
            app.samplesPerFrameUIC.ValueChangedFcn = createCallbackFcn(app, @samplesPerFrameUICValueChanged, true);
            app.samplesPerFrameUIC.Value = 2205;
            app.outGainLabel_2 = uilabel(app.GridLayout112_2);
            app.outGainLabel_2.Text = 'missed frame count';
            app.missedFrameCountUIC = uispinner(app.GridLayout112_2);
            app.missedFrameCountUIC.Limits = [0 100000];
            app.samplingfrequencyEditFieldLabel = uilabel(app.GridLayout112_2);
            app.samplingfrequencyEditFieldLabel.Text = 'sampling frequency';
            app.samplingfrequencyEditField = uieditfield(app.GridLayout112_2, 'numeric');
            app.samplingfrequencyEditField.Editable = 'off';
            app.samplingfrequencyEditField.Value = 22050;
            app.InputSelectButtonGroup = uibuttongroup(app.UIFigure);
            app.InputSelectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputSelectButtonGroupSelectionChanged, true);
            app.InputSelectButtonGroup.Title = 'Input Select';
            app.AudioFileButton = uiradiobutton(app.InputSelectButtonGroup);
            app.AudioFileButton.Text = 'Audio File';
            app.MicrophoneButton = uiradiobutton(app.InputSelectButtonGroup);
            app.MicrophoneButton.Text = 'Microphone';
            app.MicrophoneButton.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = multiThreadAudioApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end